classdef final_matlab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        urEditFieldLabel         matlab.ui.control.Label
        urEditField              matlab.ui.control.NumericEditField
        qEditFieldLabel          matlab.ui.control.Label
        qEditField               matlab.ui.control.NumericEditField
        dEditFieldLabel          matlab.ui.control.Label
        dEditField               matlab.ui.control.NumericEditField
        REditFieldLabel          matlab.ui.control.Label
        REditField               matlab.ui.control.NumericEditField
        colorEditFieldLabel      matlab.ui.control.Label
        colorEditField           matlab.ui.control.EditField
        QuiverPlotButton         matlab.ui.control.Button
        StremSlicedPlotButton    matlab.ui.control.Button
        linewidthEditFieldLabel  matlab.ui.control.Label
        linewidthEditField       matlab.ui.control.NumericEditField
        BothButton               matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuiverPlotButton
        function QuiverPlotButtonPushed(app, event)
            cla(app.UIAxes)
            ur = app.urEditField.Value;
            d = app.dEditField.Value;
            q = app.qEditField.Value;
            R = app.REditField.Value;
            clr = app.colorEditField.Value;
            lw = app.linewidthEditField.Value;
            [x,z,u,v] = magneticDipole2d(ur,d,q,R); 
            quiver(app.UIAxes,x,z,u,v,'color',clr,'linewidth',lw);
            hold(app.UIAxes,'on')
            r = d/2;
            th = 0:pi/50:2*pi;
            a = r * cos(th) + 0;
            b = r * sin(th) + 0;
            plot(app.UIAxes,a,b);
            fill(app.UIAxes,a, b, 'r')
            text(app.UIAxes,0, r/2, 'N', 'FontSize', 12, 'Color', 'g')
            text(app.UIAxes,0, 0, '\^', 'FontSize', 16, 'Color', 'g')
            text(app.UIAxes,0, 0, '|', 'FontSize', 16, 'Color', 'g')
            text(app.UIAxes,0, -r/2, 'S', 'FontSize', 12, 'Color', 'g')
            hold(app.UIAxes,'off')
            
        end

        % Button pushed function: StremSlicedPlotButton
        function StremSlicedPlotButtonPushed(app, event)
            cla(app.UIAxes)
            ur = app.urEditField.Value;
            d = app.dEditField.Value;
            q = app.qEditField.Value;
            R = app.REditField.Value;
            clr = app.colorEditField.Value;
            lw = app.linewidthEditField.Value;
            [x,z,u,v] = magneticDipole2d(ur,d,q,R);
            l = streamslice(app.UIAxes,x,z,u,v);
            set(l,'Color',clr,'LineWidth',lw);
            hold(app.UIAxes,'on')
            r = d/2;
            th = 0:pi/50:2*pi;
            a = r * cos(th) + 0;
            b = r * sin(th) + 0;
            plot(app.UIAxes,a,b);
            fill(app.UIAxes,a, b, 'r')
            text(app.UIAxes,0, r/2, 'N', 'FontSize', 12, 'Color', 'g')
            text(app.UIAxes,0, 0, '\^', 'FontSize', 16, 'Color', 'g')
            text(app.UIAxes,0, 0, '|', 'FontSize', 16, 'Color', 'g')
            text(app.UIAxes,0, -r/2, 'S', 'FontSize', 12, 'Color', 'g')
            hold(app.UIAxes,'off')
            
            
        end

        % Button pushed function: BothButton
        function BothButtonPushed(app, event)
            cla(app.UIAxes)
            ur = app.urEditField.Value;
            d = app.dEditField.Value;
            q = app.qEditField.Value;
            R = app.REditField.Value;
            clr = app.colorEditField.Value;
            lw = app.linewidthEditField.Value;
            [x,z,u,v] = magneticDipole2d(ur,d,q,R);
            quiver(app.UIAxes,x,z,u,v,'color','b','linewidth',lw);
            l = streamslice(app.UIAxes,x,z,u,v);
            set(l,'Color',clr,'LineWidth',lw);
            hold(app.UIAxes,'on')
            r = d/2;
            th = 0:pi/50:2*pi;
            a = r * cos(th) + 0;
            b = r * sin(th) + 0;
            plot(app.UIAxes,a,b);
            fill(app.UIAxes,a, b, 'r')
            text(app.UIAxes,0, r/2, 'N', 'FontSize', 12, 'Color', 'g')
            text(app.UIAxes,0, 0, '\^', 'FontSize', 16, 'Color', 'g')
            text(app.UIAxes,0, 0, '|', 'FontSize', 16, 'Color', 'g')
            text(app.UIAxes,0, -r/2, 'S', 'FontSize', 12, 'Color', 'g')
            hold(app.UIAxes,'off')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.urEditFieldLabel = uilabel(app.UIFigure);
            app.urEditFieldLabel.Text = 'ur :';
            app.urEditField = uieditfield(app.UIFigure, 'numeric');
            app.urEditField.Value = 1;
            app.qEditFieldLabel = uilabel(app.UIFigure);
            app.qEditFieldLabel.Text = 'q :';
            app.qEditField = uieditfield(app.UIFigure, 'numeric');
            app.qEditField.Value = 1;
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd :';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 6;
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R :';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Value = 50;
            app.colorEditFieldLabel = uilabel(app.UIFigure);
            app.colorEditFieldLabel.Text = 'color :';
            app.colorEditField = uieditfield(app.UIFigure, 'text');
            app.colorEditField.Value = 'b';
            app.QuiverPlotButton = uibutton(app.UIFigure, 'push');
            app.QuiverPlotButton.ButtonPushedFcn = createCallbackFcn(app, @QuiverPlotButtonPushed, true);
            app.QuiverPlotButton.Text = 'QuiverPlot';
            app.StremSlicedPlotButton = uibutton(app.UIFigure, 'push');
            app.StremSlicedPlotButton.ButtonPushedFcn = createCallbackFcn(app, @StremSlicedPlotButtonPushed, true);
            app.StremSlicedPlotButton.Text = 'StremSlicedPlot';
            app.linewidthEditFieldLabel = uilabel(app.UIFigure);
            app.linewidthEditFieldLabel.Text = 'line width :';
            app.linewidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.linewidthEditField.Value = 1;
            app.BothButton = uibutton(app.UIFigure, 'push');
            app.BothButton.ButtonPushedFcn = createCallbackFcn(app, @BothButtonPushed, true);
            app.BothButton.Text = 'Both';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Z')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_matlab_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end